classdef preturcam_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LabelPreturcamSubtitle      matlab.ui.control.Label
        LabelPreturcamTitle         matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        AnlisispredictivoTab        matlab.ui.container.Tab
        LampInfo                    matlab.ui.control.Lamp
        LabelInfo                   matlab.ui.control.Label
        LabelReiniciarTurista       matlab.ui.control.Label
        ButtonResetInfoBasica       matlab.ui.control.Button
        LabelProcesar               matlab.ui.control.Label
        ButtonProcesar              matlab.ui.control.Button
        PanelInfoBasica             matlab.ui.container.Panel
        PanelGT                     matlab.ui.container.Panel
        LampGT2                     matlab.ui.control.Lamp
        LampGT3                     matlab.ui.control.Lamp
        LampGT4                     matlab.ui.control.Lamp
        LampGT1                     matlab.ui.control.Lamp
        LampGT5                     matlab.ui.control.Lamp
        EditFieldGT                 matlab.ui.control.NumericEditField
        SliderGT                    matlab.ui.control.Slider
        PanelNV                     matlab.ui.container.Panel
        LampNV2                     matlab.ui.control.Lamp
        LampNV3                     matlab.ui.control.Lamp
        LampNV4                     matlab.ui.control.Lamp
        LampNV1                     matlab.ui.control.Lamp
        LampNV5                     matlab.ui.control.Lamp
        EditFieldNV                 matlab.ui.control.NumericEditField
        SliderNV                    matlab.ui.control.Slider
        PanelVV                     matlab.ui.container.Panel
        LampVV2                     matlab.ui.control.Lamp
        LampVV3                     matlab.ui.control.Lamp
        LampVV4                     matlab.ui.control.Lamp
        LampVV1                     matlab.ui.control.Lamp
        LampVV5                     matlab.ui.control.Lamp
        EditFieldVV                 matlab.ui.control.NumericEditField
        SliderVV                    matlab.ui.control.Slider
        PanelNP                     matlab.ui.container.Panel
        LampNP2                     matlab.ui.control.Lamp
        LampNP3                     matlab.ui.control.Lamp
        LampNP4                     matlab.ui.control.Lamp
        LampNP1                     matlab.ui.control.Lamp
        LampNP5                     matlab.ui.control.Lamp
        EditFieldNP                 matlab.ui.control.NumericEditField
        SliderNP                    matlab.ui.control.Slider
        LabelInfoBasica             matlab.ui.control.Label
        PanelInfoComplementaria     matlab.ui.container.Panel
        DropDownFV                  matlab.ui.control.DropDown
        FrecuenciadeviajesLabel     matlab.ui.control.Label
        DropDownOA                  matlab.ui.control.DropDown
        OcupacinactualLabel         matlab.ui.control.Label
        DropDownGR                  matlab.ui.control.DropDown
        GrupoLabel                  matlab.ui.control.Label
        DropDownSP                  matlab.ui.control.DropDown
        SituacinprofesionalLabel    matlab.ui.control.Label
        DropDownMVE                 matlab.ui.control.DropDown
        MotivodelviajeextLabel      matlab.ui.control.Label
        DropDownMV                  matlab.ui.control.DropDown
        MotivodelviajeLabel         matlab.ui.control.Label
        DropDownTA                  matlab.ui.control.DropDown
        TipodealojamientoLabel      matlab.ui.control.Label
        DropDownAC                  matlab.ui.control.DropDown
        AccesodeentradaLabel        matlab.ui.control.Label
        PanelDatosIdentidad         matlab.ui.container.Panel
        ButtonGroupEdad             matlab.ui.container.ButtonGroup
        Button66mas                 matlab.ui.control.RadioButton
        Button45a65                 matlab.ui.control.RadioButton
        Button25a44                 matlab.ui.control.RadioButton
        Button15a24                 matlab.ui.control.RadioButton
        KnobNacionalidad            matlab.ui.control.DiscreteKnob
        NacionalidadLabel           matlab.ui.control.Label
        SwitchGenero                matlab.ui.control.Switch
        GneroLabel                  matlab.ui.control.Label
        SeleccionestcnicasTab       matlab.ui.container.Tab
        PanelRSotros                matlab.ui.container.Panel
        EditFieldRunsRMSE           matlab.ui.control.NumericEditField
        SliderRunsRMSE              matlab.ui.control.Slider
        EjecucionesRMSELabel        matlab.ui.control.Label
        SwitchDtestmethod           matlab.ui.control.Switch
        MtododegeneracindedatosdetestLabel  matlab.ui.control.Label
        PanelGD                     matlab.ui.container.Panel
        EditFieldStddev             matlab.ui.control.NumericEditField
        KnobStddev                  matlab.ui.control.Knob
        DesviacinestndarLabel       matlab.ui.control.Label
        SliderIteracionesGD         matlab.ui.control.Slider
        IteracionesenGDSliderLabel  matlab.ui.control.Label
        SliderRunsGD                matlab.ui.control.Slider
        EjecucionesdeGDLabel        matlab.ui.control.Label
        EditFieldIteracionesGD      matlab.ui.control.NumericEditField
        EditFieldRunsGD             matlab.ui.control.NumericEditField
        SwitchItermethod            matlab.ui.control.Switch
        CriteriodeparadadeiteracionesGDLabel  matlab.ui.control.Label
        LabelRSInfo                 matlab.ui.control.Label
        ButtonRSDefault             matlab.ui.control.Button
        FactorizacinmatricialPanel  matlab.ui.container.Panel
        SwitchBiased                matlab.ui.control.Switch
        PredisposicinenlafactorizacinmatricialLabel  matlab.ui.control.Label
        EditFieldK                  matlab.ui.control.NumericEditField
        EditFieldLambda             matlab.ui.control.NumericEditField
        EditFieldBeta               matlab.ui.control.NumericEditField
        KnobLambda                  matlab.ui.control.Knob
        FactorderegularizacinLabel  matlab.ui.control.Label
        KnobBeta                    matlab.ui.control.Knob
        TasadeaprendizajeLabel      matlab.ui.control.Label
        SliderK                     matlab.ui.control.Slider
        FactoreslatentesLabel       matlab.ui.control.Label
        LabelPie                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        %---------------------------%
        %----------- Factorización matricial
        K_ini             = 32;
        beta_ini          = 0.8;
        lambda_ini        = 3;
        biased_ini        = 0;
        %----------- Gradiente en descenso
        itermethod_ini    = 0;
        num_iter_ini      = 500;
        num_runs_GD_ini   = 5;
        stddev_ini        = 0.1;
        %----------- Otros
        num_runs_RMSE_ini = 3;
        Dtestmethod_ini   = 1;
        %---------------------------
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %----------- Parámetros iniciales RS
            K = app.K_ini;
            app.SliderK.Value = K;
            app.EditFieldK.Value = K;
            
            beta = app.beta_ini;
            app.KnobBeta.Value = beta;
            app.EditFieldBeta.Value = beta;
            
            lambda = app.lambda_ini;
            app.KnobLambda.Value = lambda;
            app.EditFieldLambda.Value = lambda;            
            
            num_runs_GD = app.num_runs_GD_ini;
            app.SliderRunsGD.Value = num_runs_GD;
            app.EditFieldRunsGD.Value = num_runs_GD;        
            
            stddev = app.stddev_ini;
            app.KnobStddev.Value = stddev;
            app.EditFieldStddev.Value = stddev;
            
            itermethod = app.itermethod_ini;
            app.SwitchItermethod.Value = num2str(itermethod);
            
            num_iter = app.num_iter_ini;
            app.SliderIteracionesGD.Value = num_iter;
            app.EditFieldIteracionesGD.Value = num_iter;
            
            biased = app.biased_ini;
            app.SwitchBiased.Value = num2str(biased);
            
            num_runs_RMSE = app.num_runs_RMSE_ini;
            app.SliderRunsRMSE.Value = num_runs_RMSE;
            app.EditFieldRunsRMSE.Value = num_runs_RMSE;
            
            Dtestmethod = app.Dtestmethod_ini;
            app.SwitchDtestmethod.Value = num2str(Dtestmethod);
            %--------------
            app.LabelInfo.Text = 'Preparado para una nueva predicción.';
            app.LampInfo.Color = 'g';
            %---------------------------
        end

        % Value changed function: KnobNacionalidad
        function KnobNacionalidadValueChanged(app, event)
            nacionalidad = app.KnobNacionalidad.Value;
        end

        % Value changed function: SwitchGenero
        function SwitchGeneroValueChanged(app, event)
            genero = app.SwitchGenero.Value;
        end

        % Button pushed function: ButtonProcesar
        function ButtonProcesarPushed(app, event)
            %---------------------------
            app.LabelInfo.Text = 'Procesando... (no pulse ningún botón)';
            app.LampInfo.Color = 'r';
            pause(2);
            %--------------------------- Entradas
            %----------- Algoritmo RS
            K             = app.EditFieldK.Value;
            beta          = app.EditFieldBeta.Value;
            lambda        = app.EditFieldLambda.Value;
            num_runs_GD   = app.EditFieldRunsGD.Value;
            stddev        = app.EditFieldStddev.Value;
            itermethod    = str2double(app.SwitchItermethod.Value);
            num_iter      = app.EditFieldIteracionesGD.Value;
            biased        = str2double(app.SwitchBiased.Value);
            num_runs_RMSE = app.EditFieldRunsRMSE.Value;
            Dtestmethod   = str2double(app.SwitchDtestmethod.Value);
            %-----------
            NPND = app.EditFieldNP.Value;
            VVND = app.EditFieldVV.Value;
            NVND = app.EditFieldNV.Value;
            GTND = app.EditFieldGT.Value;
            %-----------
            strnacionalidad = app.KnobNacionalidad.Value;
            switch strnacionalidad
                case 'Francia'    , nacionalidad='FR'; NA=110;
                case 'Italia'     , nacionalidad='IT'; NA=115;
                case 'Portugal'   , nacionalidad='PT'; NA=123;
                case 'Reino Unido', nacionalidad='UK'; NA=125;
                case 'Alemania'   , nacionalidad='AL'; NA=126;
            end
            %-----------
            strgenero = app.SwitchGenero.Value;
            switch strgenero
                case 'Hombre', genero='H';
                case 'Mujer' , genero='M';
            end
            %-----------
            if     app.Button15a24.Value, rangoedad='15-24';
            elseif app.Button25a44.Value, rangoedad='25-44';
            elseif app.Button45a65.Value, rangoedad='45-65';
            else                        , rangoedad='66-M';
            end
            %-----------
            if strcmp(genero,'M') && strcmp(rangoedad,'15-24'), GE=5;  end
            if strcmp(genero,'H') && strcmp(rangoedad,'15-24'), GE=6;  end
            if strcmp(genero,'M') && strcmp(rangoedad,'25-44'), GE=7;  end
            if strcmp(genero,'H') && strcmp(rangoedad,'25-44'), GE=8;  end
            if strcmp(genero,'M') && strcmp(rangoedad,'45-65'), GE=9;  end
            if strcmp(genero,'H') && strcmp(rangoedad,'45-65'), GE=10; end
            if strcmp(genero,'M') && strcmp(rangoedad,'66-M'),  GE=11; end
            if strcmp(genero,'H') && strcmp(rangoedad,'66-M'),  GE=12; end
            %-----------
            AC  = app.DropDownAC.Value;
            TA  = app.DropDownTA.Value;
            MV  = app.DropDownMV.Value;
            OA  = app.DropDownOA.Value;
            SP  = app.DropDownSP.Value;
            MVE = app.DropDownMVE.Value;
            FV  = app.DropDownFV.Value;
            GR  = app.DropDownGR.Value;
            %=========================== Procesar
            [PNPND,PVVND,PNVND,PGTND,MRstr,Nusers] = preturcam(nacionalidad,genero,rangoedad,NPND,VVND,NVND,GTND,NA,GE,AC,TA,MV,OA,SP,MVE,FV,GR,Dtestmethod,K,beta,lambda,num_runs_GD,stddev,itermethod,num_iter,biased,num_runs_RMSE);
            %===========================
            app.LampInfo.Color = 'g';
            app.LabelInfo.Text = 'Predicción finalizada. Preparado para una nueva predicción.';
            %--------------------------- Salidas
            %-----------
            if NPND == 0
                app.EditFieldNP.Value     = PNPND;
                app.SliderNP.Value        = PNPND;
                switch PNPND
                    case 1, app.LampNP1.Color = '#ffcccc';
                    case 2, app.LampNP2.Color = '#ff6666';
                    case 3, app.LampNP3.Color = '#ff0000';
                    case 4, app.LampNP4.Color = '#990000';
                    case 5, app.LampNP5.Color = '#330000';
                end
            end
            if VVND == 0
                app.EditFieldVV.Value     = PVVND;
                app.SliderVV.Value        = PVVND;
                switch PVVND
                    case 1, app.LampVV1.Color = '#ffcccc';
                    case 2, app.LampVV2.Color = '#ff6666';
                    case 3, app.LampVV3.Color = '#ff0000';
                    case 4, app.LampVV4.Color = '#990000';
                    case 5, app.LampVV5.Color = '#330000';
                end
            end
            if NVND == 0
                app.EditFieldNV.Value     = PNVND;
                app.SliderNV.Value        = PNVND;
                switch PNVND
                    case 1, app.LampNV1.Color = '#ffcccc';
                    case 2, app.LampNV2.Color = '#ff6666';
                    case 3, app.LampNV3.Color = '#ff0000';
                    case 4, app.LampNV4.Color = '#990000';
                    case 5, app.LampNV5.Color = '#330000';
                end
            end
            if GTND == 0
                app.EditFieldGT.Value     = PGTND;
                app.SliderGT.Value        = PGTND;
                switch PGTND
                    case 1, app.LampGT1.Color = '#ffcccc';
                    case 2, app.LampGT2.Color = '#ff6666';
                    case 3, app.LampGT3.Color = '#ff0000';
                    case 4, app.LampGT4.Color = '#990000';
                    case 5, app.LampGT5.Color = '#330000';
                end
            end
            %-----------
            
            %---------------------------
        end

        % Selection changed function: ButtonGroupEdad
        function ButtonGroupEdadSelectionChanged(app, event)
            selectedButton = app.ButtonGroupEdad.SelectedObject;
        end

        % Value changing function: SliderNP
        function SliderNPValueChanging(app, event)
            %------------------
            NP = event.Value;
            NP = round(NP);
            app.LampNP1.Color = 'w';
            app.LampNP2.Color = 'w';
            app.LampNP3.Color = 'w';
            app.LampNP4.Color = 'w';
            app.LampNP5.Color = 'w';
            app.EditFieldNP.Value = NP;
            switch NP
                case 1, app.LampNP1.Color = '#ffcccc';
                case 2, app.LampNP2.Color = '#ff6666';
                case 3, app.LampNP3.Color = '#ff0000';
                case 4, app.LampNP4.Color = '#990000';
                case 5, app.LampNP5.Color = '#330000';
            end
            %------------------ 
        end

        % Value changing function: SliderVV
        function SliderVVValueChanging(app, event)
            %------------------
            VV = event.Value;
            VV = round(VV);
            app.LampVV1.Color = 'w';
            app.LampVV2.Color = 'w';
            app.LampVV3.Color = 'w';
            app.LampVV4.Color = 'w';
            app.LampVV5.Color = 'w';
            app.EditFieldVV.Value = VV;
            switch VV
                case 1, app.LampVV1.Color = '#ffcccc';
                case 2, app.LampVV2.Color = '#ff6666';
                case 3, app.LampVV3.Color = '#ff0000';
                case 4, app.LampVV4.Color = '#990000';
                case 5, app.LampVV5.Color = '#330000';
            end
            %------------------ 
        end

        % Value changing function: SliderNV
        function SliderNVValueChanging(app, event)
            %------------------
            NV = event.Value;
            NV = round(NV);
            app.LampNV1.Color = 'w';
            app.LampNV2.Color = 'w';
            app.LampNV3.Color = 'w';
            app.LampNV4.Color = 'w';
            app.LampNV5.Color = 'w';
            app.EditFieldNV.Value = NV;
            switch NV
                case 1, app.LampNV1.Color = '#ffcccc';
                case 2, app.LampNV2.Color = '#ff6666';
                case 3, app.LampNV3.Color = '#ff0000';
                case 4, app.LampNV4.Color = '#990000';
                case 5, app.LampNV5.Color = '#330000';
            end
            %------------------ 
        end

        % Value changing function: SliderGT
        function SliderGTValueChanging(app, event)
            %------------------
            GT = event.Value;
            GT = round(GT);
            app.LampGT1.Color = 'w';
            app.LampGT2.Color = 'w';
            app.LampGT3.Color = 'w';
            app.LampGT4.Color = 'w';
            app.LampGT5.Color = 'w';
            app.EditFieldGT.Value = GT;
            switch GT
                case 1, app.LampGT1.Color = '#ffcccc';
                case 2, app.LampGT2.Color = '#ff6666';
                case 3, app.LampGT3.Color = '#ff0000';
                case 4, app.LampGT4.Color = '#990000';
                case 5, app.LampGT5.Color = '#330000';
            end
            %------------------ 
        end

        % Button pushed function: ButtonResetInfoBasica
        function ButtonResetInfoBasicaPushed(app, event)
            %------------------
            app.DropDownAC.Value  = 0;
            app.DropDownTA.Value  = 0;
            app.DropDownMV.Value  = 0;
            app.DropDownMVE.Value = 0;
            app.DropDownSP.Value  = 0;
            app.DropDownGR.Value  = 0;
            app.DropDownOA.Value  = 0;
            app.DropDownFV.Value  = 0;
            %-----------
            app.LampNP1.Color = 'w';
            app.LampNP2.Color = 'w';
            app.LampNP3.Color = 'w';
            app.LampNP4.Color = 'w';
            app.LampNP5.Color = 'w';
            app.EditFieldNP.Value = 0;
            app.SliderNP.Value    = 0;
            %-----------
            app.LampVV1.Color = 'w';
            app.LampVV2.Color = 'w';
            app.LampVV3.Color = 'w';
            app.LampVV4.Color = 'w';
            app.LampVV5.Color = 'w';
            app.EditFieldVV.Value = 0;
            app.SliderVV.Value    = 0;
            %-----------
            app.LampNV1.Color = 'w';
            app.LampNV2.Color = 'w';
            app.LampNV3.Color = 'w';
            app.LampNV4.Color = 'w';
            app.LampNV5.Color = 'w';
            app.EditFieldNV.Value = 0;
            app.SliderNV.Value    = 0;
            %-----------
            app.LampGT1.Color = 'w';
            app.LampGT2.Color = 'w';
            app.LampGT3.Color = 'w';
            app.LampGT4.Color = 'w';
            app.LampGT5.Color = 'w';
            app.EditFieldGT.Value = 0;
            app.SliderGT.Value    = 0;
            %-----------
            %------------------
        end

        % Button pushed function: ButtonRSDefault
        function ButtonRSDefaultPushed(app, event)
            %------------------
            K                                = app.K_ini;
            app.SliderK.Value                = K;
            app.EditFieldK.Value             = K;
            
            beta                             = app.beta_ini;
            app.KnobBeta.Value               = beta;
            app.EditFieldBeta.Value          = beta;
            
            lambda                           = app.lambda_ini;
            app.KnobLambda.Value             = lambda;
            app.EditFieldLambda.Value        = lambda;
            
            num_runs_GD                      = app.num_runs_GD_ini;
            app.SliderRunsGD.Value           = num_runs_GD;
            app.EditFieldRunsGD.Value        = num_runs_GD;
            
            stddev                           = app.stddev_ini;
            app.KnobStddev.Value             = stddev;
            app.EditFieldStddev.Value        = stddev;
            
            itermethod                       = app.itermethod_ini;
            app.SwitchItermethod.Value       = num2str(itermethod);
            
            num_iter                         = app.num_iter_ini;
            app.SliderIteracionesGD.Value    = num_iter;
            app.EditFieldIteracionesGD.Value = num_iter;
            
            biased                           = app.biased_ini;
            app.SwitchBiased.Value           = num2str(biased);
            
            num_runs_RMSE                    = app.num_runs_RMSE_ini;
            app.SliderRunsRMSE.Value         = num_runs_RMSE;
            app.EditFieldRunsRMSE.Value      = num_runs_RMSE;
            
            Dtestmethod                      = app.Dtestmethod_ini;
            app.SwitchDtestmethod.Value      = num2str(Dtestmethod);
            %------------------
        end

        % Value changing function: SliderK
        function SliderKValueChanging(app, event)
            %------------------
            K = event.Value;
            K = round(K);
            app.EditFieldK.Value = K;
            %------------------ 
        end

        % Value changing function: KnobBeta
        function KnobBetaValueChanging(app, event)
            %------------------
            beta = event.Value;
            app.EditFieldBeta.Value = beta;
            %------------------             
        end

        % Value changing function: KnobLambda
        function KnobLambdaValueChanging(app, event)
            %------------------
            lambda = event.Value;
            app.EditFieldLambda.Value = lambda;
            %------------------             
        end

        % Value changed function: SwitchBiased
        function SwitchBiasedValueChanged(app, event)
            %------------------
            biased = str2double(app.SwitchBiased.Value);
            %------------------             
        end

        % Value changed function: SwitchItermethod
        function SwitchItermethodValueChanged(app, event)
            %------------------
            itermethod = str2double(app.SwitchItermethod.Value);
            %------------------ 
        end

        % Value changing function: SliderIteracionesGD
        function SliderIteracionesGDValueChanging(app, event)
            %------------------
            num_iter = event.Value;
            num_iter = round(num_iter);
            app.EditFieldIteracionesGD.Value = num_iter;
            %------------------ 
        end

        % Value changing function: SliderRunsGD
        function SliderRunsGDValueChanging(app, event)
            %------------------
            num_runs_GD = event.Value;
            num_runs_GD = round(num_runs_GD);
            app.EditFieldRunsGD.Value = num_runs_GD;
            %------------------ 
        end

        % Value changing function: SliderRunsRMSE
        function SliderRunsRMSEValueChanging(app, event)
            %------------------
            num_runs_RMSE = event.Value;
            num_runs_RMSE = round(num_runs_RMSE);
            app.EditFieldRunsRMSE.Value = num_runs_RMSE;
            %------------------ 
        end

        % Value changed function: SwitchDtestmethod
        function SwitchDtestmethodValueChanged(app, event)
            %------------------
            Dtestmethod = str2double(app.SwitchDtestmethod.Value);
            %------------------ 
        end

        % Value changing function: KnobStddev
        function KnobStddevValueChanging(app, event)
            %------------------
            stddev = event.Value;
            app.EditFieldStddev.Value = stddev;
            %------------------ 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabelPie = uilabel(app.UIFigure);
            app.LabelPie.Text = '(c) JAGP 2022 - Universidad de Extremadura. Proyecto CITIZEN LAB';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnlisispredictivoTab = uitab(app.TabGroup);
            app.AnlisispredictivoTab.Title = 'Análisis predictivo';
            app.PanelDatosIdentidad = uipanel(app.AnlisispredictivoTab);
            app.PanelDatosIdentidad.Title = 'Datos de identidad (obligatorios)';
            app.GneroLabel = uilabel(app.PanelDatosIdentidad);
            app.GneroLabel.Text = 'Género';
            app.SwitchGenero = uiswitch(app.PanelDatosIdentidad, 'slider');
            app.SwitchGenero.Items = {'Hombre', 'Mujer'};
            app.SwitchGenero.ValueChangedFcn = createCallbackFcn(app, @SwitchGeneroValueChanged, true);
            app.SwitchGenero.Value = 'Hombre';
            app.NacionalidadLabel = uilabel(app.PanelDatosIdentidad);
            app.NacionalidadLabel.Text = 'Nacionalidad';
            app.KnobNacionalidad = uiknob(app.PanelDatosIdentidad, 'discrete');
            app.KnobNacionalidad.Items = {'Alemania', 'Francia', 'Italia', 'Portugal', 'Reino Unido'};
            app.KnobNacionalidad.ValueChangedFcn = createCallbackFcn(app, @KnobNacionalidadValueChanged, true);
            app.KnobNacionalidad.Value = 'Alemania';
            app.ButtonGroupEdad = uibuttongroup(app.PanelDatosIdentidad);
            app.ButtonGroupEdad.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupEdadSelectionChanged, true);
            app.ButtonGroupEdad.Title = 'Edad';
            app.Button15a24 = uiradiobutton(app.ButtonGroupEdad);
            app.Button15a24.Text = '15 a 24';
            app.Button15a24.Value = true;
            app.Button25a44 = uiradiobutton(app.ButtonGroupEdad);
            app.Button25a44.Text = '25 a 44';
            app.Button45a65 = uiradiobutton(app.ButtonGroupEdad);
            app.Button45a65.Text = '45 a 65';
            app.Button66mas = uiradiobutton(app.ButtonGroupEdad);
            app.Button66mas.Text = 'Más de 66';
            app.PanelInfoComplementaria = uipanel(app.AnlisispredictivoTab);
            app.PanelInfoComplementaria.Title = 'Información complementaria';
            app.AccesodeentradaLabel = uilabel(app.PanelInfoComplementaria);
            app.AccesodeentradaLabel.Text = 'Acceso de entrada';
            app.DropDownAC = uidropdown(app.PanelInfoComplementaria);
            app.DropDownAC.Items = {'S/D', 'Carretera', 'Aeropuerto', 'Puerto marítimo', 'Tren'};
            app.DropDownAC.ItemsData = [0 1 2 3 4];
            app.DropDownAC.Value = 0;
            app.TipodealojamientoLabel = uilabel(app.PanelInfoComplementaria);
            app.TipodealojamientoLabel.Text = 'Tipo de alojamiento';
            app.DropDownTA = uidropdown(app.PanelInfoComplementaria);
            app.DropDownTA.Items = {'S/D', 'Hoteles o similares', 'Alojamiento en alquiler', 'Camping', 'Casa Rural', 'Crucero', 'Otro alojamiento de mercado', 'Vivienda en propiedad', 'Vivienda de familiares/amigos', 'Otro alojamiento no de mercado'};
            app.DropDownTA.ItemsData = [0 1 2 3 4 5 6 7 8 9];
            app.DropDownTA.Value = 0;
            app.MotivodelviajeLabel = uilabel(app.PanelInfoComplementaria);
            app.MotivodelviajeLabel.Text = 'Motivo del viaje';
            app.DropDownMV = uidropdown(app.PanelInfoComplementaria);
            app.DropDownMV.Items = {'S/D', 'Ocio, vacaciones', 'Negocios', 'Estudios', 'Personal (salud, familia)', 'Otros motivos'};
            app.DropDownMV.ItemsData = [0 1 2 3 4 5];
            app.DropDownMV.Value = 0;
            app.MotivodelviajeextLabel = uilabel(app.PanelInfoComplementaria);
            app.MotivodelviajeextLabel.Text = 'Motivo del viaje (ext.)';
            app.DropDownMVE = uidropdown(app.PanelInfoComplementaria);
            app.DropDownMVE.Items = {'S/D', 'Asistencia a ferias congresos y convenciones', 'Trabajador estacional (temporero)', 'Otros motivos de trabajo y negocios', 'Estudios (educación y formación)', 'Visitas a familiares y amigos', 'Tratamiento de salud voluntario', 'Motivos religiosos o peregrinaciones', 'Compras o servicios personales', 'Turismo gastronómico', 'Turismo cultural', 'Práctica deportiva', 'Turismo de sol y playa', 'Turismo de naturaleza', 'Incentivos de empresa', 'Otros tipos de ocio', 'Otros'};
            app.DropDownMVE.ItemsData = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.DropDownMVE.Value = 0;
            app.SituacinprofesionalLabel = uilabel(app.PanelInfoComplementaria);
            app.SituacinprofesionalLabel.Text = 'Situación profesional';
            app.DropDownSP = uidropdown(app.PanelInfoComplementaria);
            app.DropDownSP.Items = {'S/D', 'Empresario autónomo', 'Asalariado cargo alto', 'Asalariado cargo medio', 'Asalariado sin cualificación'};
            app.DropDownSP.ItemsData = [0 1 2 3 4];
            app.DropDownSP.Value = 0;
            app.GrupoLabel = uilabel(app.PanelInfoComplementaria);
            app.GrupoLabel.Text = 'Grupo';
            app.DropDownGR = uidropdown(app.PanelInfoComplementaria);
            app.DropDownGR.Items = {'S/D', 'Sólo', 'Con su pareja', 'Con mi familia incluyendo hijos', 'Con mi familia sin incluir hijos', 'Con mi familia y amigos', 'Con amigos', 'Con compañeros de trabajo/estudios'};
            app.DropDownGR.ItemsData = [0 1 2 3 4 5 6 7];
            app.DropDownGR.Value = 0;
            app.OcupacinactualLabel = uilabel(app.PanelInfoComplementaria);
            app.OcupacinactualLabel.Text = 'Ocupación actual';
            app.DropDownOA = uidropdown(app.PanelInfoComplementaria);
            app.DropDownOA.Items = {'S/D', 'Ocupado, trabajando', 'Jubilado, retirado', 'Parado (buscando trabajo)', 'Estudiante', 'Labores del hogar', 'Otro (rentista, servicio militar, etc.)'};
            app.DropDownOA.ItemsData = [0 81 82 83 84 85 86];
            app.DropDownOA.Value = 0;
            app.FrecuenciadeviajesLabel = uilabel(app.PanelInfoComplementaria);
            app.FrecuenciadeviajesLabel.Text = 'Frecuencia de viajes';
            app.DropDownFV = uidropdown(app.PanelInfoComplementaria);
            app.DropDownFV.Items = {'S/D', 'Semanalmente, en fin de semana', 'Semanalmente, entre semana', 'Una vez por mes', 'Una vez por trimestre', 'Una vez por semestre', 'Una vez al año', 'Menor frecuencia'};
            app.DropDownFV.ItemsData = [0 1 2 3 4 5 6 7];
            app.DropDownFV.Value = 0;
            app.PanelInfoBasica = uipanel(app.AnlisispredictivoTab);
            app.PanelInfoBasica.Title = 'Información básica cualitativa';
            app.LabelInfoBasica = uilabel(app.PanelInfoBasica);
            app.LabelInfoBasica.Text = 'Consignar solo los datos conocidos';
            app.PanelNP = uipanel(app.PanelInfoBasica);
            app.PanelNP.Title = 'Nº de pernoctaciones';
            app.SliderNP = uislider(app.PanelNP);
            app.SliderNP.Limits = [0 5];
            app.SliderNP.MajorTicks = [0 1 2 3 4 5];
            app.SliderNP.MajorTickLabels = {'(desconocido)', 'muy pocas', 'pocas', 'medio', 'bastantes', 'muchas'};
            app.SliderNP.ValueChangingFcn = createCallbackFcn(app, @SliderNPValueChanging, true);
            app.EditFieldNP = uieditfield(app.PanelNP, 'numeric');
            app.LampNP5 = uilamp(app.PanelNP);
            app.LampNP1 = uilamp(app.PanelNP);
            app.LampNP4 = uilamp(app.PanelNP);
            app.LampNP3 = uilamp(app.PanelNP);
            app.LampNP2 = uilamp(app.PanelNP);
            app.PanelVV = uipanel(app.PanelInfoBasica);
            app.PanelVV.Title = 'Valoración del viaje';
            app.SliderVV = uislider(app.PanelVV);
            app.SliderVV.Limits = [0 5];
            app.SliderVV.MajorTicks = [0 1 2 3 4 5];
            app.SliderVV.MajorTickLabels = {'(desconocido)', 'pésima', 'mala', 'regular', 'buena', 'excelente'};
            app.SliderVV.ValueChangingFcn = createCallbackFcn(app, @SliderVVValueChanging, true);
            app.EditFieldVV = uieditfield(app.PanelVV, 'numeric');
            app.LampVV5 = uilamp(app.PanelVV);
            app.LampVV1 = uilamp(app.PanelVV);
            app.LampVV4 = uilamp(app.PanelVV);
            app.LampVV3 = uilamp(app.PanelVV);
            app.LampVV2 = uilamp(app.PanelVV);
            app.PanelNV = uipanel(app.PanelInfoBasica);
            app.PanelNV.Title = 'Nº de visitas anteriores';
            app.SliderNV = uislider(app.PanelNV);
            app.SliderNV.Limits = [0 5];
            app.SliderNV.MajorTicks = [0 1 2 3 4 5];
            app.SliderNV.MajorTickLabels = {'(desconocido)', 'muy pocas', 'pocas', 'medio', 'bastantes', 'muchas'};
            app.SliderNV.ValueChangingFcn = createCallbackFcn(app, @SliderNVValueChanging, true);
            app.EditFieldNV = uieditfield(app.PanelNV, 'numeric');
            app.LampNV5 = uilamp(app.PanelNV);
            app.LampNV1 = uilamp(app.PanelNV);
            app.LampNV4 = uilamp(app.PanelNV);
            app.LampNV3 = uilamp(app.PanelNV);
            app.LampNV2 = uilamp(app.PanelNV);
            app.PanelGT = uipanel(app.PanelInfoBasica);
            app.PanelGT.Title = 'Gasto total';
            app.SliderGT = uislider(app.PanelGT);
            app.SliderGT.Limits = [0 5];
            app.SliderGT.MajorTicks = [0 1 2 3 4 5];
            app.SliderGT.MajorTickLabels = {'(desconocido)', 'muy bajo', 'bajo', 'medio', 'alto', 'muy alto'};
            app.SliderGT.ValueChangingFcn = createCallbackFcn(app, @SliderGTValueChanging, true);
            app.EditFieldGT = uieditfield(app.PanelGT, 'numeric');
            app.LampGT5 = uilamp(app.PanelGT);
            app.LampGT1 = uilamp(app.PanelGT);
            app.LampGT4 = uilamp(app.PanelGT);
            app.LampGT3 = uilamp(app.PanelGT);
            app.LampGT2 = uilamp(app.PanelGT);
            app.ButtonProcesar = uibutton(app.AnlisispredictivoTab, 'push');
            app.ButtonProcesar.ButtonPushedFcn = createCallbackFcn(app, @ButtonProcesarPushed, true);
            app.ButtonProcesar.Text = 'Procesar';
            app.LabelProcesar = uilabel(app.AnlisispredictivoTab);
            app.LabelProcesar.Text = 'Realliza una predicción de los datos desconocidos de la información básica.';
            app.ButtonResetInfoBasica = uibutton(app.AnlisispredictivoTab, 'push');
            app.ButtonResetInfoBasica.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetInfoBasicaPushed, true);
            app.ButtonResetInfoBasica.Text = 'Reiniciar turista';
            app.LabelReiniciarTurista = uilabel(app.AnlisispredictivoTab);
            app.LabelReiniciarTurista.Text = 'Reinicia la información para un nuevo turista';
            app.LabelInfo = uilabel(app.AnlisispredictivoTab);
            app.LampInfo = uilamp(app.AnlisispredictivoTab);
            app.SeleccionestcnicasTab = uitab(app.TabGroup);
            app.SeleccionestcnicasTab.Title = 'Selecciones técnicas';
            app.FactorizacinmatricialPanel = uipanel(app.SeleccionestcnicasTab);
            app.FactorizacinmatricialPanel.Title = 'Factorización matricial';
            app.FactoreslatentesLabel = uilabel(app.FactorizacinmatricialPanel);
            app.FactoreslatentesLabel.Text = 'Factores latentes';
            app.SliderK = uislider(app.FactorizacinmatricialPanel);
            app.SliderK.Limits = [5 40];
            app.SliderK.MajorTicks = [5 10 15 20 25 30 35 40];
            app.SliderK.MajorTickLabels = {'5', '10', '15', '20', '25', '30', '35', '40'};
            app.SliderK.ValueChangingFcn = createCallbackFcn(app, @SliderKValueChanging, true);
            app.SliderK.Value = 5;
            app.TasadeaprendizajeLabel = uilabel(app.FactorizacinmatricialPanel);
            app.TasadeaprendizajeLabel.Text = 'Tasa de aprendizaje';
            app.KnobBeta = uiknob(app.FactorizacinmatricialPanel, 'continuous');
            app.KnobBeta.Limits = [0.1 0.9];
            app.KnobBeta.MajorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.KnobBeta.MajorTickLabels = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.KnobBeta.ValueChangingFcn = createCallbackFcn(app, @KnobBetaValueChanging, true);
            app.KnobBeta.Value = 0.1;
            app.FactorderegularizacinLabel = uilabel(app.FactorizacinmatricialPanel);
            app.FactorderegularizacinLabel.Text = 'Factor de regularización';
            app.KnobLambda = uiknob(app.FactorizacinmatricialPanel, 'continuous');
            app.KnobLambda.Limits = [0.1 5];
            app.KnobLambda.MajorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.KnobLambda.MajorTickLabels = {'0.1', '0.5', '1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5'};
            app.KnobLambda.ValueChangingFcn = createCallbackFcn(app, @KnobLambdaValueChanging, true);
            app.KnobLambda.Value = 0.1;
            app.EditFieldBeta = uieditfield(app.FactorizacinmatricialPanel, 'numeric');
            app.EditFieldLambda = uieditfield(app.FactorizacinmatricialPanel, 'numeric');
            app.EditFieldK = uieditfield(app.FactorizacinmatricialPanel, 'numeric');
            app.PredisposicinenlafactorizacinmatricialLabel = uilabel(app.FactorizacinmatricialPanel);
            app.PredisposicinenlafactorizacinmatricialLabel.Text = 'Predisposición en la factorización matricial';
            app.SwitchBiased = uiswitch(app.FactorizacinmatricialPanel, 'slider');
            app.SwitchBiased.Items = {'Sin predisposición', 'Con predisposición'};
            app.SwitchBiased.ItemsData = {'0', '1'};
            app.SwitchBiased.ValueChangedFcn = createCallbackFcn(app, @SwitchBiasedValueChanged, true);
            app.SwitchBiased.Value = '0';
            app.ButtonRSDefault = uibutton(app.SeleccionestcnicasTab, 'push');
            app.ButtonRSDefault.ButtonPushedFcn = createCallbackFcn(app, @ButtonRSDefaultPushed, true);
            app.ButtonRSDefault.Text = 'Valores por defecto';
            app.LabelRSInfo = uilabel(app.SeleccionestcnicasTab);
            app.LabelRSInfo.Text = 'Estas variables configuran los algoritmos predictivos. No deberían cambiarse sin conocimiento detallado de sus funcionalidades.';
            app.PanelGD = uipanel(app.SeleccionestcnicasTab);
            app.PanelGD.Title = 'Gradiente en descenso';
            app.CriteriodeparadadeiteracionesGDLabel = uilabel(app.PanelGD);
            app.CriteriodeparadadeiteracionesGDLabel.Text = 'Criterio de parada de iteraciones GD';
            app.SwitchItermethod = uiswitch(app.PanelGD, 'slider');
            app.SwitchItermethod.Items = {'Nº de iteraciones', 'Error estancado'};
            app.SwitchItermethod.ItemsData = {'0', '1'};
            app.SwitchItermethod.ValueChangedFcn = createCallbackFcn(app, @SwitchItermethodValueChanged, true);
            app.SwitchItermethod.Value = '0';
            app.EditFieldRunsGD = uieditfield(app.PanelGD, 'numeric');
            app.EditFieldIteracionesGD = uieditfield(app.PanelGD, 'numeric');
            app.EjecucionesdeGDLabel = uilabel(app.PanelGD);
            app.EjecucionesdeGDLabel.Text = 'Ejecuciones de GD';
            app.SliderRunsGD = uislider(app.PanelGD);
            app.SliderRunsGD.Limits = [1 31];
            app.SliderRunsGD.ValueChangingFcn = createCallbackFcn(app, @SliderRunsGDValueChanging, true);
            app.SliderRunsGD.Value = 1;
            app.IteracionesenGDSliderLabel = uilabel(app.PanelGD);
            app.IteracionesenGDSliderLabel.Text = 'Iteraciones en GD';
            app.SliderIteracionesGD = uislider(app.PanelGD);
            app.SliderIteracionesGD.Limits = [100 900];
            app.SliderIteracionesGD.MajorTicks = [100 300 500 700 900];
            app.SliderIteracionesGD.MajorTickLabels = {'100', '300', '500', '700', '900'};
            app.SliderIteracionesGD.ValueChangingFcn = createCallbackFcn(app, @SliderIteracionesGDValueChanging, true);
            app.SliderIteracionesGD.Value = 100;
            app.DesviacinestndarLabel = uilabel(app.PanelGD);
            app.DesviacinestndarLabel.Text = 'Desviación estándar';
            app.KnobStddev = uiknob(app.PanelGD, 'continuous');
            app.KnobStddev.Limits = [0.01 0.9];
            app.KnobStddev.MajorTicks = [0.01 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9];
            app.KnobStddev.MajorTickLabels = {'0.01', '0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9'};
            app.KnobStddev.ValueChangingFcn = createCallbackFcn(app, @KnobStddevValueChanging, true);
            app.KnobStddev.Value = 0.1;
            app.EditFieldStddev = uieditfield(app.PanelGD, 'numeric');
            app.PanelRSotros = uipanel(app.SeleccionestcnicasTab);
            app.PanelRSotros.Title = 'Otros';
            app.MtododegeneracindedatosdetestLabel = uilabel(app.PanelRSotros);
            app.MtododegeneracindedatosdetestLabel.Text = 'Método de generación de datos de test';
            app.SwitchDtestmethod = uiswitch(app.PanelRSotros, 'slider');
            app.SwitchDtestmethod.Items = {'Uno por fila, columnas seguidas', 'Como datos entrenamiento'};
            app.SwitchDtestmethod.ItemsData = {'1', '2'};
            app.SwitchDtestmethod.ValueChangedFcn = createCallbackFcn(app, @SwitchDtestmethodValueChanged, true);
            app.SwitchDtestmethod.Value = '1';
            app.EjecucionesRMSELabel = uilabel(app.PanelRSotros);
            app.EjecucionesRMSELabel.Text = 'Ejecuciones RMSE';
            app.SliderRunsRMSE = uislider(app.PanelRSotros);
            app.SliderRunsRMSE.Limits = [1 31];
            app.SliderRunsRMSE.ValueChangingFcn = createCallbackFcn(app, @SliderRunsRMSEValueChanging, true);
            app.SliderRunsRMSE.Value = 1;
            app.EditFieldRunsRMSE = uieditfield(app.PanelRSotros, 'numeric');
            app.LabelPreturcamTitle = uilabel(app.UIFigure);
            app.LabelPreturcamTitle.Text = 'PRETURCAM';
            app.LabelPreturcamSubtitle = uilabel(app.UIFigure);
            app.LabelPreturcamSubtitle.Text = 'Predicción del comportamiento del turista extranjero que visita la Comunidad Autónoma de Madrid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preturcam_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end